classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup_2                matlab.ui.container.TabGroup
        BlueTab_2                 matlab.ui.container.Tab
        UIAxesB_2                 matlab.ui.control.UIAxes
        GreenTab_2                matlab.ui.container.Tab
        UIAxesG_2                 matlab.ui.control.UIAxes
        RedTab_2                  matlab.ui.container.Tab
        UIAxesR_2                 matlab.ui.control.UIAxes
        GrayTab_2                 matlab.ui.container.Tab
        UIAxesGray_2              matlab.ui.control.UIAxes
        TabGroup                  matlab.ui.container.TabGroup
        GrayTab                   matlab.ui.container.Tab
        UIAxesGray                matlab.ui.control.UIAxes
        RedTab                    matlab.ui.container.Tab
        UIAxesR                   matlab.ui.control.UIAxes
        GreenTab                  matlab.ui.container.Tab
        UIAxesG                   matlab.ui.control.UIAxes
        BlueTab                   matlab.ui.container.Tab
        UIAxesB                   matlab.ui.control.UIAxes
        ProcessButton             matlab.ui.control.Button
        EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel            matlab.ui.control.Label
        cEditField_2              matlab.ui.control.NumericEditField
        cEditField_2Label         matlab.ui.control.Label
        PowerTransformationLabel  matlab.ui.control.Label
        rEditField                matlab.ui.control.NumericEditField
        rEditFieldLabel           matlab.ui.control.Label
        cEditField                matlab.ui.control.NumericEditField
        cEditFieldLabel           matlab.ui.control.Label
        LogTransformationLabel    matlab.ui.control.Label
        BrighteningLabel          matlab.ui.control.Label
        bEditField                matlab.ui.control.NumericEditField
        bEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.NumericEditField
        aEditFieldLabel           matlab.ui.control.Label
        NegativeDropDown          matlab.ui.control.DropDown
        NegativeDropDownLabel     matlab.ui.control.Label
        InputImageButton          matlab.ui.control.Button
        OperationDropDown         matlab.ui.control.DropDown
        OperationDropDownLabel    matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        InputImg % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputImageButton
        function InputImageButtonPushed(app, event)
            % Open file selection dialog for supported formats
            [file, path] = uigetfile({'*.bmp;*.png;*.jpg;*.jpeg;*.gif;*.svg', ...
                                      'All Image Files (*.bmp, *.png, *.jpg, *.jpeg, *.gif, *.svg)'; ...
                                      '*.*', 'All Files (*.*)'}, ...
                                     'Select an Image File');
            % Check if user selected a file or clicked cancel
            if isequal(file, 0)
                uialert(app.UIFigure, 'No file selected', 'File Selection');
                return;
            end
        
            % Full file path
            filepath = fullfile(path, file);
            
            % Try to read and convert the image if it's a BMP file
            [~, ~, ext] = fileparts(filepath);
            if strcmpi(ext, '.bmp')
                % Convert BMP to a temporary PNG file
                img = imread(filepath);
                tempPath = fullfile(tempdir, 'temp_image.png');
                imwrite(img, tempPath);
                
                % Update the Image component's ImageSource property
                app.Image.ImageSource = tempPath;
            else
                % Use the selected file directly if it's already a supported format
                app.Image.ImageSource = filepath;
            end
            
            if ndims(imread(filepath)) == 3
                displayHistogram(imread(app.Image.ImageSource), [app.UIAxesR, app.UIAxesG, app.UIAxesB]);
            else
                displayHistogram(imread(app.Image.ImageSource), app.UIAxesGray);
            end
            
        end

        % Value changed function: OperationDropDown
        function OperationDropDownValueChanged(app, event)
            value = app.OperationDropDown.Value;
            if isequal(value, "Image Enhancement")
                app.BrighteningLabel.Visible = "on";
                app.EditField.Visible = "on";
                app.NegativeDropDown.Visible = "on";
                app.NegativeDropDownLabel.Visible = "on";
                app.LogTransformationLabel.Visible = "on";
                app.PowerTransformationLabel.Visible = "on";
                app.aEditField.Visible = "on";
                app.aEditFieldLabel.Visible = "on";
                app.bEditField.Visible = "on";
                app.bEditFieldLabel.Visible = "on";
                app.cEditField.Visible = "on";
                app.cEditFieldLabel.Visible = "on";
                app.cEditField_2.Visible = "on";
                app.cEditField_2Label.Visible = "on";
                app.rEditField.Visible = "on";
                app.rEditFieldLabel.Visible = "on";
            else
                app.BrighteningLabel.Visible = "off";
                app.EditField.Visible = "off";
                app.NegativeDropDown.Visible = "off";
                app.NegativeDropDownLabel.Visible = "off";
                app.LogTransformationLabel.Visible = "off";
                app.PowerTransformationLabel.Visible = "off";
                app.aEditField.Visible = "off";
                app.aEditFieldLabel.Visible = "off";
                app.bEditField.Visible = "off";
                app.bEditFieldLabel.Visible = "off";
                app.cEditField.Visible = "off";
                app.cEditFieldLabel.Visible = "off";
                app.cEditField_2.Visible = "off";
                app.cEditField_2Label.Visible = "off";
                app.rEditField.Visible = "off";
                app.rEditFieldLabel.Visible = "off";
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            originalImg = imread(app.Image.ImageSource);
            
            enhancedImg = brightening(originalImg, app.aEditField.Value, app.bEditField.Value);
            
            if isequal(app.NegativeDropDown.Value, "Negative")
                enhancedImg = negative(enhancedImg);
            end
            app.Image_2.ImageSource = enhancedImg;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image_2 = uiimage(app.UIFigure);
            app.OperationDropDownLabel = uilabel(app.UIFigure);
            app.OperationDropDownLabel.Text = 'Operation';
            app.OperationDropDown = uidropdown(app.UIFigure);
            app.OperationDropDown.Items = {'Image Enhancement', 'Histogram Equilization', 'Histogram Specification'};
            app.OperationDropDown.ValueChangedFcn = createCallbackFcn(app, @OperationDropDownValueChanged, true);
            app.OperationDropDown.Value = 'Image Enhancement';
            app.InputImageButton = uibutton(app.UIFigure, 'push');
            app.InputImageButton.ButtonPushedFcn = createCallbackFcn(app, @InputImageButtonPushed, true);
            app.InputImageButton.Text = 'Input Image';
            app.NegativeDropDownLabel = uilabel(app.UIFigure);
            app.NegativeDropDownLabel.Text = 'Negative';
            app.NegativeDropDown = uidropdown(app.UIFigure);
            app.NegativeDropDown.Items = {'None', 'Negative', 'Inverse Negative'};
            app.NegativeDropDown.Value = 'None';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.Value = 1;
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.BrighteningLabel = uilabel(app.UIFigure);
            app.BrighteningLabel.Text = 'Brightening';
            app.LogTransformationLabel = uilabel(app.UIFigure);
            app.LogTransformationLabel.Text = 'Log Transformation';
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.rEditFieldLabel = uilabel(app.UIFigure);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerTransformationLabel = uilabel(app.UIFigure);
            app.PowerTransformationLabel.Text = 'Power Transformation';
            app.cEditField_2Label = uilabel(app.UIFigure);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '𝛾';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GrayTab = uitab(app.TabGroup);
            app.GrayTab.Title = 'Gray';
            app.UIAxesGray = uiaxes(app.GrayTab);
            title(app.UIAxesGray, 'Title')
            xlabel(app.UIAxesGray, 'X')
            ylabel(app.UIAxesGray, 'Y')
            zlabel(app.UIAxesGray, 'Z')
            app.RedTab = uitab(app.TabGroup);
            app.RedTab.Title = 'Red';
            app.UIAxesR = uiaxes(app.RedTab);
            title(app.UIAxesR, 'Title')
            xlabel(app.UIAxesR, 'X')
            ylabel(app.UIAxesR, 'Y')
            zlabel(app.UIAxesR, 'Z')
            app.GreenTab = uitab(app.TabGroup);
            app.GreenTab.Title = 'Green';
            app.UIAxesG = uiaxes(app.GreenTab);
            title(app.UIAxesG, 'Title')
            xlabel(app.UIAxesG, 'X')
            ylabel(app.UIAxesG, 'Y')
            zlabel(app.UIAxesG, 'Z')
            app.BlueTab = uitab(app.TabGroup);
            app.BlueTab.Title = 'Blue';
            app.UIAxesB = uiaxes(app.BlueTab);
            title(app.UIAxesB, 'Title')
            xlabel(app.UIAxesB, 'X')
            ylabel(app.UIAxesB, 'Y')
            zlabel(app.UIAxesB, 'Z')
            app.TabGroup_2 = uitabgroup(app.UIFigure);
            app.GrayTab_2 = uitab(app.TabGroup_2);
            app.GrayTab_2.Title = 'Gray';
            app.UIAxesGray_2 = uiaxes(app.GrayTab_2);
            title(app.UIAxesGray_2, 'Title')
            xlabel(app.UIAxesGray_2, 'X')
            ylabel(app.UIAxesGray_2, 'Y')
            zlabel(app.UIAxesGray_2, 'Z')
            app.RedTab_2 = uitab(app.TabGroup_2);
            app.RedTab_2.Title = 'Red';
            app.UIAxesR_2 = uiaxes(app.RedTab_2);
            title(app.UIAxesR_2, 'Title')
            xlabel(app.UIAxesR_2, 'X')
            ylabel(app.UIAxesR_2, 'Y')
            zlabel(app.UIAxesR_2, 'Z')
            app.GreenTab_2 = uitab(app.TabGroup_2);
            app.GreenTab_2.Title = 'Green';
            app.UIAxesG_2 = uiaxes(app.GreenTab_2);
            title(app.UIAxesG_2, 'Title')
            xlabel(app.UIAxesG_2, 'X')
            ylabel(app.UIAxesG_2, 'Y')
            zlabel(app.UIAxesG_2, 'Z')
            app.BlueTab_2 = uitab(app.TabGroup_2);
            app.BlueTab_2.Title = 'Blue';
            app.UIAxesB_2 = uiaxes(app.BlueTab_2);
            title(app.UIAxesB_2, 'Title')
            xlabel(app.UIAxesB_2, 'X')
            ylabel(app.UIAxesB_2, 'Y')
            zlabel(app.UIAxesB_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end